classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectDatasetButton           matlab.ui.control.Button
        VisualizationtypeButtonGroup  matlab.ui.container.ButtonGroup
        DifferenceImageButton         matlab.ui.control.RadioButton
        TimelapseButton               matlab.ui.control.RadioButton
        SliderButton                  matlab.ui.control.RadioButton
        ColordistributionButton       matlab.ui.control.RadioButton
        AnalyzeButton                 matlab.ui.control.Button
        ModeofChangeDropDownLabel     matlab.ui.control.Label
        ModeofChangeDropDown          matlab.ui.control.DropDown
        FeatureMatchingButtonGroup    matlab.ui.container.ButtonGroup
        StrictButton                  matlab.ui.control.ToggleButton
        RelaxedButton                 matlab.ui.control.ToggleButton
        ImageAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        viz_type % which type of visualization to produce
        mode_change % which mode of visualization chosen in Drop down
        target_folder % Folder path containing images to be processed
    end
    
    methods (Access = private)
        
        function update_image(app,image)
            
            imagesc(app.ImageAxes, image);
        end
        
        function welcome(app)
            message = {'Hi there!', ' ', 'This is just a quick info for you!', ' ',  'First, you have to browse a scene with the Browse button.', ' ', 'Second you can choose the visualization mode and type to your preference.', ' ', 'Now, you can start the app with the "Analyse" button.', ' ', 'With the LOOP button, you can choose to play the videos in an infinite loop.', ' ', 'ENJOY :)' };
            
            d = dialog('Position',[300 300 500 300],'Name','Welcome!');
            
            txt = uicontrol('Parent',d,...
                'Style','text',...
                'Position',[40 80 420 200],...
                'String',message);
            
            btn = uicontrol('Parent',d,...
                'Position',[220 20 70 25],...
                'String','Close',...
                'Callback','delete(gcf)');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            welcome(app);
            app.target_folder='.';
            app.viz_type=app.VisualizationtypeButtonGroup.SelectedObject.Text
            
            
        end

        % Callback function: ImageAxes, SelectDatasetButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            app.target_folder = uigetdir('Load a dataset ');
            if app.target_folder==0
                app.target_folder='.';
                return
            end
            % Make sure user didn't cancel uigetfile dialog
            if ~isfolder(app.target_folder)
                errorMessage = sprintf('Error: The target folder does not exist:\n%s', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            imageFiles=dir([app.target_folder '/*.jpg']);
            n_images=length(imageFiles);
            
            if n_images==0
                errordlg('No Images found in Source Folder! Images must be of type .jpg. Please select a fitting data set!' ,'No Images Found');
                return
            end
            
            switch app.viz_type
                case 'Timelapse'
                    
                    %create a timelapse visualization
                    image_Array=timelapse(app.target_folder);
                    update_image(app,image_Array)
                case 'Difference Image'
                    %create difference image visualization
                    
                    origFileName= fullfile(app.target_folder, imageFiles(1).name);
                    movedFileName= fullfile(app.target_folder, imageFiles(2).name);
                    
                    
                    %TODO:Move grayscaling to preprocessing step!
                    
                    original= rgb2gray(imread(origFileName));
                    moved=rgb2gray(imread(movedFileName));
                    
                    moved_registered=register(original,moved);
                    
                    image_out=imfuse(original,moved_registered,'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
                    
                    update_image(app, image_out);
                    
                case 'Slider'
                    
                case 'Color distribution?'
            end
        end

        % Selection changed function: VisualizationtypeButtonGroup
        function VisualizationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VisualizationtypeButtonGroup.SelectedObject;
            app.viz_type=selectedButton.Text;
        end

        % Value changed function: ModeofChangeDropDown
        function ModeofChangeDropDownValueChanged(app, event)
            value = app.ModeofChangeDropDown.Value;
            app.mode_change = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectDatasetButton = uibutton(app.UIFigure, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.VisualizationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.VisualizationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationtypeButtonGroupSelectionChanged, true);
            app.VisualizationtypeButtonGroup.Title = 'Visualization type';
            app.DifferenceImageButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.DifferenceImageButton.Text = 'Difference Image';
            app.DifferenceImageButton.Value = true;
            app.TimelapseButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.TimelapseButton.Text = 'Timelapse';
            app.SliderButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.ColordistributionButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.ColordistributionButton.Text = 'Color distribution?';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ModeofChangeDropDownLabel = uilabel(app.UIFigure);
            app.ModeofChangeDropDownLabel.Text = 'Mode of Change';
            app.ModeofChangeDropDown = uidropdown(app.UIFigure);
            app.ModeofChangeDropDown.Items = {'Quick', 'Slow', 'Little', 'Large'};
            app.ModeofChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeofChangeDropDownValueChanged, true);
            app.ModeofChangeDropDown.Value = 'Quick';
            app.FeatureMatchingButtonGroup = uibuttongroup(app.UIFigure);
            app.FeatureMatchingButtonGroup.Title = 'Feature Matching';
            app.StrictButton = uitogglebutton(app.FeatureMatchingButtonGroup);
            app.StrictButton.Text = 'Strict';
            app.StrictButton.Value = true;
            app.RelaxedButton = uitogglebutton(app.FeatureMatchingButtonGroup);
            app.RelaxedButton.Text = 'Relaxed';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end